classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MostrarVecinosButton     matlab.ui.control.Button
        DropDownVecinos          matlab.ui.control.DropDown
        DropDownVecinosLabel     matlab.ui.control.Label
        LabelResultado           matlab.ui.control.Label
        DropDownDistancias       matlab.ui.control.DropDown
        DropDownDistanciasLabel  matlab.ui.control.Label
        CargarimagenButton       matlab.ui.control.Button
        CalcularDistanciaButton  matlab.ui.control.Button
        ImgGris                  matlab.ui.control.UIAxes
        ImgResultado             matlab.ui.control.UIAxes
        ImgOriginal              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         ImagenOriginal  % Guarda la foto RGB a color
         ImagenGris      % Guarda tu foto en escala de grises
         X_size          % Ancho
         Y_size          % Alto
    end
    
    methods (Access = private)
        
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularDistanciaButton
        function calcDist(app, event)
    % Validamos que ya haya una imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error de validación');
                return;
            end

            % Avisamos al usuario
            app.LabelResultado.Text = 'Haz clic en el PRIMER punto (origen)...';
            pto1 = drawpoint(app.ImgOriginal); 
            
            app.LabelResultado.Text = 'Haz clic en el SEGUNDO punto (destino)...';
            pto2 = drawpoint(app.ImgOriginal);
            
            % Extraemos las coordenadas X e Y redondeadas
            P1 = [round(pto1.Position(1)), round(pto1.Position(2))];
            P2 = [round(pto2.Position(1)), round(pto2.Position(2))];
            
            % Borramos los puntitos visuales para limpiar la foto
            delete(pto1);
            delete(pto2);

            % Revisamos que los clics no se salgan del tamaño de la imagen
            if P1(1) < 1 || P1(1) > app.X_size || P1(2) < 1 || P1(2) > app.Y_size || ...
               P2(1) < 1 || P2(1) > app.X_size || P2(2) < 1 || P2(2) > app.Y_size
                
                app.LabelResultado.Text = 'Error: Diste clic fuera de la imagen :P';
                return;
            end
            
            % Leemos qué distancia seleccionó en el DropDown
            tipoDistancia = app.DropDownDistancias.Value;
            
            % Calculamos la distancia
            if strcmp(tipoDistancia, 'Euclidiana')
                d = distEuclidiana(P1, P2);
            elseif strcmp(tipoDistancia, 'Manhattan')
                d = distManhattan(P1, P2);
            elseif strcmp(tipoDistancia, 'Ajedrez')
                d = distAjedrez(P1, P2);
            end
            
            % Imprimimos el resultado final en la etiqueta
            app.LabelResultado.Text = sprintf('Distancia %s: %.2f píxeles', tipoDistancia, d);
        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
                [archivo, ruta] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Imagenes (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                    'Selecciona la imagen de tu equipo');
                if archivo == 0
                    return;
                end
                rutaCompleta = fullfile(ruta, archivo);
                app.ImagenOriginal = imread(rutaCompleta);

                imshow(app.ImagenOriginal, 'Parent', app.ImgOriginal);

                app.X_size = 0;
                app.Y_size = 0;

                for x = app.ImagenOriginal(1, 1:end, 1)
                    app.X_size = app.X_size + 1;
                end

                for y = app.ImagenOriginal(1:end, 1, 1)'
                    app.Y_size = app.Y_size + 1;
                end

                app.ImagenGris = obtenerGrisManual(app.ImagenOriginal);
                imshow(app.ImagenGris, 'Parent', app.ImgGris);

                disp('Imagen cargada en los Axes y dimensiones calculadas.');

        end

        % Button pushed function: MostrarVecinosButton
        function MostrarVecinosButtonPushed(app, event)

                % Validar que la imagen y su version en gris existan
                if isempty(app.ImagenOriginal)
                    uialert(app.UIFigure, 'Carga una imagen primero', 'Error');
                    return;
                end

                % Por seguridad, si la imagen gris no se generó, la forzamos aquí
                if isempty(app.ImagenGris)
                    app.ImagenGris = rgb2gray(app.ImagenOriginal);
                end

                % Pedimos el clic con drawpoint
                app.LabelResultado.Text = 'Haz 1 clic en el pixel central...';
                pto = drawpoint(app.ImgGris); 

                % Extraemos la coordenada y borramos el punto visual
                Px = round(pto.Position(1));
                Py = round(pto.Position(2));
                delete(pto);

                % Validar límites para no salirnos de la matriz
                if Px < 1 || Px > app.X_size || Py < 1 || Py > app.Y_size
                    app.LabelResultado.Text = 'Error: Clic fuera de la matriz.';
                    return;
                end

                % Leemos qué vecindad seleccionó en el DropDown
                tipoVecindad = app.DropDownVecinos.Value;

                % Calculamos los vecinos llamando a tus funciones personalizadas 
                if strcmp(tipoVecindad, '8-vecinos')
                    vecinos = obtenerVecindad8(app.ImagenGris, Px, Py);
                elseif strcmp(tipoVecindad, '4-vecinos')
                    vecinos = obtenerVecindad4(app.ImagenGris, Px, Py);
                elseif strcmp(tipoVecindad, 'D-vecinos')
                    vecinos = obtenerVecindadD(app.ImagenGris, Px, Py);
                end

                % Convertimos el arreglo de salida en texto y lo imprimimos
                textoMatriz = mat2str(vecinos);
                app.LabelResultado.Text = sprintf('%s: %s', tipoVecindad, textoMatriz);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImgOriginal = uiaxes(app.UIFigure);
            title(app.ImgOriginal, 'Imagen Original')
            app.ImgOriginal.XTick = [];
            app.ImgOriginal.YTick = [];
            app.ImgResultado = uiaxes(app.UIFigure);
            title(app.ImgResultado, 'Resultados')
            app.ImgResultado.XTick = [];
            app.ImgResultado.XTickLabel = '';
            app.ImgResultado.YTick = [];
            app.ImgGris = uiaxes(app.UIFigure);
            title(app.ImgGris, 'Escala de Grises')
            app.ImgGris.XTick = [];
            app.ImgGris.XTickLabel = '';
            app.ImgGris.YTick = [];
            app.CalcularDistanciaButton = uibutton(app.UIFigure, 'push');
            app.CalcularDistanciaButton.ButtonPushedFcn = createCallbackFcn(app, @calcDist, true);
            app.CalcularDistanciaButton.Text = 'Calcular Distancia';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.DropDownDistanciasLabel = uilabel(app.UIFigure);
            app.DropDownDistanciasLabel.Text = 'DropDownDistancias';
            app.DropDownDistancias = uidropdown(app.UIFigure);
            app.DropDownDistancias.Items = {'Euclidiana', 'Manhattan', 'Ajedrez'};
            app.DropDownDistancias.Value = 'Euclidiana';
            app.LabelResultado = uilabel(app.UIFigure);
            app.LabelResultado.Text = 'LabelResultado';
            app.DropDownVecinosLabel = uilabel(app.UIFigure);
            app.DropDownVecinosLabel.Text = 'DropDownVecinos';
            app.DropDownVecinos = uidropdown(app.UIFigure);
            app.DropDownVecinos.Items = {'4-Vecinos', '8-Vecinos', 'D-Vecinos'};
            app.DropDownVecinos.Value = '4-Vecinos';
            app.MostrarVecinosButton = uibutton(app.UIFigure, 'push');
            app.MostrarVecinosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarVecinosButtonPushed, true);
            app.MostrarVecinosButton.Text = 'Mostrar Vecinos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end